classdef Vocales < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        HombreButton_2                matlab.ui.control.Button
        HombreButton                  matlab.ui.control.Button
        Grabeunaudiode5segundosLabel  matlab.ui.control.Label
        IntroduzcaunarchivodeaudioLabel  matlab.ui.control.Label
        CargararchivodeaudioButton    matlab.ui.control.Button
        ArchivoEditField              matlab.ui.control.EditField
        NombredelarchivoLabel         matlab.ui.control.Label
        IntroduzcaunaudioLabel        matlab.ui.control.Label
        IdentificacindevocalesLabel   matlab.ui.control.Label
        VocalLabel                    matlab.ui.control.Label
        subtituloLabel                matlab.ui.control.Label
        AnalizaraudioButton           matlab.ui.control.Button
        ReproduciraudioButton         matlab.ui.control.Button
        GrabarunaudioButton           matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Audio_vec % Description
    end
    
    methods (Access = private)
        
        function GraficarAudio(app)
            cla(app.UIAxes, 'reset');
            xline(app.UIAxes, 0);
            plot(app.UIAxes, app.Audio_vec); 
            grid(app.UIAxes, 'on');
            title(app.UIAxes,'Audio');
            xlabel(app.UIAxes,'n'); 
            ylabel(app.UIAxes,'F(n)'); 
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GrabarunaudioButton
        function GrabarunaudioButtonPushed(app, event)
            cla(app.UIAxes,'reset');
            % Graba audio por 5 segundos
            recObj = audiorecorder;
            disp('Inicio de la grabación')
            app.GrabarunaudioButton.Text = "Grabando audio...";
            recordblocking(recObj, 5);
            disp('Fin de la grabación');
           
            % Guarda los datos obtenidos en un vector
            app.Audio_vec = getaudiodata(recObj);
            % Grafica el vector obtenido del audio
            %plot(app.UIAxes, app.Audio_vec);
            GraficarAudio(app);
            app.GrabarunaudioButton.Text = "Grabar un audio";
        end

        % Button pushed function: ReproduciraudioButton
        function ReproduciraudioButtonPushed(app, event)
             % Play back the recording.
             %play(recObj);
             load chirp.mat;
             sound(app.Audio_vec, Fs);
        end

        % Button pushed function: CargararchivodeaudioButton
        function CargararchivodeaudioButtonPushed(app, event)
            %Carga el nombre del archivo del audio
            filename = app.ArchivoEditField.Value;
            load handel.mat
            
            % Guarda el audio y los canales
            audiowrite(filename,app.Audio_vec,Fs);
            
            % Grafica el vector obtenido del audio
            %plot(app.UIAxes, app.Audio_vec);
            GraficarAudio(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Audio')
            xlabel(app.UIAxes, 'n')
            ylabel(app.UIAxes, 'F(n)')
            zlabel(app.UIAxes, 'Z')
            app.GrabarunaudioButton = uibutton(app.UIFigure, 'push');
            app.GrabarunaudioButton.ButtonPushedFcn = createCallbackFcn(app, @GrabarunaudioButtonPushed, true);
            app.GrabarunaudioButton.Text = 'Grabar un audio';
            app.ReproduciraudioButton = uibutton(app.UIFigure, 'push');
            app.ReproduciraudioButton.ButtonPushedFcn = createCallbackFcn(app, @ReproduciraudioButtonPushed, true);
            app.ReproduciraudioButton.Text = 'Reproducir audio';
            app.AnalizaraudioButton = uibutton(app.UIFigure, 'push');
            app.AnalizaraudioButton.Text = 'Analizar audio';
            app.subtituloLabel = uilabel(app.UIFigure);
            app.subtituloLabel.Text = 'La vocal que usted dijo es: ';
            app.VocalLabel = uilabel(app.UIFigure);
            app.VocalLabel.Text = 'A';
            app.IdentificacindevocalesLabel = uilabel(app.UIFigure);
            app.IdentificacindevocalesLabel.Text = 'Identificación de vocales';
            app.IntroduzcaunaudioLabel = uilabel(app.UIFigure);
            app.IntroduzcaunaudioLabel.Text = 'Introduzca un audio';
            app.NombredelarchivoLabel = uilabel(app.UIFigure);
            app.NombredelarchivoLabel.Text = 'Nombre del archivo:';
            app.ArchivoEditField = uieditfield(app.UIFigure, 'text');
            app.CargararchivodeaudioButton = uibutton(app.UIFigure, 'push');
            app.CargararchivodeaudioButton.ButtonPushedFcn = createCallbackFcn(app, @CargararchivodeaudioButtonPushed, true);
            app.CargararchivodeaudioButton.Text = 'Cargar archivo de audio';
            app.IntroduzcaunarchivodeaudioLabel = uilabel(app.UIFigure);
            app.IntroduzcaunarchivodeaudioLabel.Text = 'Introduzca un archivo de audio:';
            app.Grabeunaudiode5segundosLabel = uilabel(app.UIFigure);
            app.Grabeunaudiode5segundosLabel.Text = 'Grabe un audio de 5 segundos:';
            app.HombreButton = uibutton(app.UIFigure, 'push');
            app.HombreButton.Text = 'Hombre';
            app.HombreButton_2 = uibutton(app.UIFigure, 'push');
            app.HombreButton_2.Text = 'Hombre';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Vocales

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end